classdef basic_lab_main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BasicCommLabSystemUIFigure      matlab.ui.Figure
        PresettingsMenu                 matlab.ui.container.Menu
        SourceCodingMenu                matlab.ui.container.Menu
        ModulationMenu                  matlab.ui.container.Menu
        ChannelCodingMenu               matlab.ui.container.Menu
        PulseShapingMenu                matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        MessageArrow                    matlab.ui.control.Image
        SourceButton                    matlab.ui.control.Button
        SourceEncodingButton            matlab.ui.control.Button
        UArrow                          matlab.ui.control.Image
        ChannelEncodingButton           matlab.ui.control.Button
        CArrow                          matlab.ui.control.Image
        ModulationButton                matlab.ui.control.Button
        SinkButton                      matlab.ui.control.Button
        SourceDecodingButton            matlab.ui.control.Button
        ChannelDecodingButton           matlab.ui.control.Button
        DemapperButton                  matlab.ui.control.Button
        RunButton                       matlab.ui.control.Button
        MessageHatArrow                 matlab.ui.control.Image
        UHatArrow                       matlab.ui.control.Image
        LArrow                          matlab.ui.control.Image
        PulseShapingButton              matlab.ui.control.Button
        ReceiveFilterButton             matlab.ui.control.Button
        XArrow                          matlab.ui.control.Image
        DArrow                          matlab.ui.control.Image
        CarrierModulationButton         matlab.ui.control.Button
        CarrierDemodulationButton       matlab.ui.control.Button
        SArrow                          matlab.ui.control.Image
        RArrow                          matlab.ui.control.Image
        ChannelButton                   matlab.ui.control.Button
        SHFArrow                        matlab.ui.control.Image
        RHFArrow                        matlab.ui.control.Image
        ParameterButton                 matlab.ui.control.Button
        ActiveBlocksPanel               matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        SourceCodingCheckBox            matlab.ui.control.CheckBox
        ChannelCodingCheckBox           matlab.ui.control.CheckBox
        ModulationCheckBox              matlab.ui.control.CheckBox
        PulseShapingCheckBox            matlab.ui.control.CheckBox
        CarrierModulationCheckBox       matlab.ui.control.CheckBox
        EqualizerCheckBox               matlab.ui.control.CheckBox
        ChannelCheckBox                 matlab.ui.control.CheckBox
        EqualizerButton                 matlab.ui.control.Button
        YArrow                          matlab.ui.control.Image
        ResultsPanel                    matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        CompressionLabel                matlab.ui.control.Label
        CompressionResult               matlab.ui.control.Label
        EndToEndSerLabel                matlab.ui.control.Label
        EndToEndSerResult               matlab.ui.control.Label
        BitErrorRateLabel               matlab.ui.control.Label
        BER                             matlab.ui.control.Label
        ExportParamButton               matlab.ui.control.Button
        UpToDateLabel                   matlab.ui.control.Label
        GridLayout4                     matlab.ui.container.GridLayout
        OversamplingFactorSpinnerLabel  matlab.ui.control.Label
        OversamplingFactorSpinner       matlab.ui.control.Spinner
        SymbolRateSpinnerLabel          matlab.ui.control.Label
        SymbolRateEditField             matlab.ui.control.Spinner
    end

    
    properties (Access = private)
        parameterApp
        
        results_up_to_date
    end
    
    
    properties (Access = public)
        param % Description
        results
        ResultGrid
        root
        enabled_controls
        
        version
        
        source_dialog_app
        source_encoding_dialog_app
        modulation_dialog_app
        channel_dialog_app
        pulse_dialog_app
        carrier_dialog_app
        equalizer_dialog_app
        
        message
        message_hat
        u
        u_hat
        c
        L
        x
        y
        d
        s
        s_hf
        r
        r_hf
        is_run
    end
    
    methods (Access = private)
        
        
        function disable_all(app)
            %set( findall(app.BasicCommLabSystemUIFigure, '-property', 'Enable'), 'Enable', 'off')
            app.PresettingsMenu.Enable = false;
            app.ActiveBlocksPanel.Visible = 'off';
            app.RunButton.Enable = false;
            app.ParameterButton.Enable = false;
            app.ExportParamButton.Enable = false;
            
            app.SourceButton.Enable = false;
            app.SinkButton.Enable = false;
            app.SourceEncodingButton.Enable = false;
            app.SourceDecodingButton.Enable = false;
            app.ChannelEncodingButton.Enable = false;
            app.ChannelDecodingButton.Enable = false;
            app.ModulationButton.Enable = false;
            app.DemapperButton.Enable = false;
            app.PulseShapingButton.Enable = false;
            app.ReceiveFilterButton.Enable = false;
            app.CarrierModulationButton.Enable = false;
            app.CarrierDemodulationButton.Enable = false;
            app.ChannelButton.Enable = false;
            app.EqualizerButton.Enable = false;
            
            app.MessageArrow.Enable = false;
            app.UArrow.Enable = false;
            app.CArrow.Enable = false;
            app.XArrow.Enable = false;
            app.SArrow.Enable = false;
            app.SHFArrow.Enable = false;
            
            app.RHFArrow.Enable = false;
            app.RArrow.Enable = false;
            app.YArrow.Enable = false;
            app.DArrow.Enable = false;
            app.LArrow.Enable = false;
            app.UHatArrow.Enable = false;
            app.MessageHatArrow.Enable = false;
            
        end
        
        function enable_all(app,with_arrows)
            app.PresettingsMenu.Enable = true;
            app.ActiveBlocksPanel.Visible = 'on';
            app.ParameterButton.Enable = true;
            app.RunButton.Enable = true;
            app.ExportParamButton.Enable = true;
            
            app.SourceButton.Enable = true;
            app.SinkButton.Enable = true;
            if app.enabled_controls.SourceButtons
                app.SourceEncodingButton.Enable = true;
                app.SourceDecodingButton.Enable = true;
            end
            if app.enabled_controls.ChannelCodingButtons
                app.ChannelEncodingButton.Enable = true;
                app.ChannelDecodingButton.Enable = true;
                if with_arrows
                    app.CArrow.Enable = true;
                    app.UHatArrow.Enable = true;
                end
            end
            if app.enabled_controls.ModulationButtons
                app.ModulationButton.Enable = true;
                app.DemapperButton.Enable = true;
                if with_arrows
                    app.XArrow.Enable = true;
                    app.LArrow.Enable = true;
                end
            end
            if app.enabled_controls.PulseButtons
                app.PulseShapingButton.Enable = true;
                app.ReceiveFilterButton.Enable = true;
                app.SArrow.Enable = true;
                app.YArrow.Enable = true;
            end
            if app.enabled_controls.CarrierButtons
                app.CarrierModulationButton.Enable = true;
                app.CarrierDemodulationButton.Enable = true;
                if with_arrows
                    app.SHFArrow.Enable = true;
                    app.RArrow.Enable = true;
                end
            end
            if app.enabled_controls.ChannelButton
                app.ChannelButton.Enable = true;
                if with_arrows
                    app.RHFArrow.Enable = true;
                end
            end
            if app.enabled_controls.EqualizerButton
                app.EqualizerButton.Enable = true;
                if with_arrows
                    app.DArrow.Enable = true;
                end
            end
            
            if with_arrows
                app.MessageArrow.Enable = true;
                app.UArrow.Enable = true;
                app.MessageHatArrow.Enable = true;
            end
            
        end
        
        function show_results(app)
            app.CompressionResult.Text = sprintf('%.3f',app.results.compression_factor);
            app.EndToEndSerResult.Text = sprintf('%.3e',app.results.end_to_end_ser);
            app.BER.Text = sprintf('%.3e', app.results.u_ber);
        end
        
        
    end

    
    methods (Access = public)
        
        function update_shown_parameters(app)
            if isobject(app.parameterApp)
                if isvalid(app.parameterApp)
                    show_all_parameters(app.parameterApp,app.param);
                end
            end
        end
        function disable_arrows(app)
            app.MessageArrow.Enable = false;
            app.UArrow.Enable = false;
            app.CArrow.Enable = false;
            app.XArrow.Enable = false;
            app.SArrow.Enable = false;
            app.SHFArrow.Enable = false;
            
            app.RHFArrow.Enable = false;
            app.RArrow.Enable = false;
            app.YArrow.Enable = false;
            app.DArrow.Enable = false;
            app.LArrow.Enable = false;
            app.UHatArrow.Enable = false;
            app.MessageHatArrow.Enable = false;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            helper.initialize_paths(app);
            
            %app.version = 'cm';
            
            f = fopen(fullfile(app.root,'gui','version.txt'));
            if f == -1
                app.version = 'carrier_modulation'; %invalid file, use highest version
            else
                app.version = fgetl(f);
            end
            helper.initialize_lab_param(app);
            app.results_up_to_date = false;
            app.is_run = false;
            
            % load arrow images
            app.MessageArrow.ImageSource = fullfile(app.root,'gui','img','message_arrow.png');
            app.UArrow.ImageSource = fullfile(app.root,'gui','img','u_arrow.png');
            app.CArrow.ImageSource = fullfile(app.root,'gui','img','c_arrow.png');
            app.XArrow.ImageSource = fullfile(app.root,'gui','img','x_long_arrow.png');
            app.SArrow.ImageSource = fullfile(app.root,'gui','img','s_arrow.png');
            app.SHFArrow.ImageSource = fullfile(app.root,'gui','img','s_hf_arrow.png');
            if app.CarrierModulationCheckBox.Value
                app.RHFArrow.ImageSource = fullfile(app.root,'gui','img','r_hf_arrow.png');
            elseif app.PulseShapingCheckBox.Value
                app.RHFArrow.ImageSource = fullfile(app.root,'gui','img','r_arrow.png');
            else
                app.RHFArrow.ImageSource = fullfile(app.root,'gui','img','y_vert_arrow.png');
            end
            %app.RHFArrow.ImageSource = fullfile(app.root,'gui','img','y_vert_arrow.png');
            app.RArrow.ImageSource = fullfile(app.root,'gui','img','r_arrow.png');
            app.DArrow.ImageSource = fullfile(app.root,'gui','img','d_arrow.png');
            app.YArrow.ImageSource = fullfile(app.root,'gui','img','y_arrow.png');
            app.LArrow.ImageSource = fullfile(app.root,'gui','img','L_arrow.png');
            app.UHatArrow.ImageSource = fullfile(app.root,'gui','img','u_hat_arrow.png');
            app.MessageHatArrow.ImageSource = fullfile(app.root,'gui','img','m_hat_arrow.png');
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)


            app.disable_all();
            drawnow;
            
            try
                %if ~app.param.source.is_initialized
                %    app.param = source.initialize(app.param);
                %end
                %app.param = source_encoding.initialize(app.param);
                %app.param = channel_encoding.initialize(app.param);
                %app.param = modulation.initialize(app.param);
                %app.param = pulse_shaping.initialize(app.param);
                %app.param = channel.initialize(app.param);
                %app.param = carrier_demodulation.initialize(app.param);
                %app.param = matched_filter.initialize(app.param);
                app.param = initialize_param(app.param);
                
                app.results = run_simulation(app.param);
                
                app.show_results();
            catch ME
                uialert(app.BasicCommLabSystemUIFigure,ME.message,'Error');
                app.enable_all(false);
                helper.close_open_signals(app);
                drawnow;
                throw(ME);
            end
            
            if ~app.is_run
                app.is_run = true;
                app.UpToDateLabel.Visible = 'off';
                app.UpToDateLabel.Text = 'Results might be not up to date due to changes in settings. Click Run button to run simulation.';
            else
                app.UpToDateLabel.Visible = 'off';
            end
            app.enable_all(true);
            helper.update_open_signals(app);
            drawnow;
            
        end

        % Button pushed function: ParameterButton
        function ParameterButtonPushed(app, event)
            if isobject(app.parameterApp) && isvalid(app.parameterApp)
                app.parameterApp.UIFigure.Visible = 'off';
                app.parameterApp.UIFigure.Visible = 'on';
            else
                app.parameterApp = show_parameter(app.param,app);
                app.update_shown_parameters();
            end

        end

        % Button pushed function: ExportParamButton
        function ExportParamButtonPushed(app, event)
            param = app.param;
            save(fullfile(app.root,'files','exported_param.mat'),'param');
            message = sprintf('Parameters successfully exported to:\n\n%s\n\nRun the file basic_lab_script.m in the main folder to simulate your system without the GUI.',...
                fullfile(app.root,'files','exported_param.mat'));
            uialert(app.BasicCommLabSystemUIFigure, message, ...
                'Parameter exported', 'Icon', 'success');
        end

        % Close request function: BasicCommLabSystemUIFigure
        function BasicCommLabSystemUIFigureCloseRequest(app, event)
%             selection = uiconfirm(app.BasicCommLabSystemUIFigure,...
%                 'Do you want to save your current parameters for the next time before closing the program?',...
%                 'Save Parameters?',...
%                 'Options', {'No', 'Yes', 'Cancel'},...
%                 'DefaultOption',2, 'CancelOption', 3);
%             switch selection
%                 case 'No'
%                     param = [];
%                 case 'Yes'
%                     param = app.param;
%                 case 'Cancel'
%                     return
%             end
%             save(fullfile(app.root,'gui','param.mat'),'param');
            helper.close_open_signals(app);
            helper.close_open_dialogs(app);
            if isobject(app.parameterApp) && isvalid(app.parameterApp)
                delete(app.parameterApp);
            end
            delete(app);
            
            
        end

        % Button pushed function: SourceButton
        function SourceButtonPushed(app, event)
            if isobject(app.source_dialog_app) && isvalid(app.source_dialog_app)
                app.source_dialog_app.SourceSettingsUIFigure.Visible = 'off';
                app.source_dialog_app.SourceSettingsUIFigure.Visible = 'on';
            else
                app.source_dialog_app = source_dialog(app);
            end
            
        end

        % Button pushed function: SourceEncodingButton
        function SourceEncodingButtonPushed(app, event)
            if isobject(app.source_encoding_dialog_app) && isvalid(app.source_encoding_dialog_app)
                app.source_encoding_dialog_app.UIFigure.Visible = 'off';
                app.source_encoding_dialog_app.UIFigure.Visible = 'on';
            else
                app.source_encoding_dialog_app = source_encoding_dialog(app);
            end
        end

        % Button pushed function: SourceDecodingButton
        function SourceDecodingButtonPushed(app, event)
            if isobject(app.source_encoding_dialog_app) && isvalid(app.source_encoding_dialog_app)
                app.source_encoding_dialog_app.UIFigure.Visible = 'off';
                app.source_encoding_dialog_app.UIFigure.Visible = 'on';
            else
                app.source_encoding_dialog_app = source_encoding_dialog(app);
            end
        end

        % Button pushed function: ModulationButton
        function ModulationButtonPushed(app, event)
            if isobject(app.modulation_dialog_app) && isvalid(app.modulation_dialog_app)
                app.modulation_dialog_app.UIFigure.Visible = 'off';
                app.modulation_dialog_app.UIFigure.Visible = 'on';
            else
                app.modulation_dialog_app = modulation_dialog(app);
            end
        end

        % Button pushed function: DemapperButton
        function DemapperButtonPushed(app, event)
            if isobject(app.modulation_dialog_app) && isvalid(app.modulation_dialog_app)
                app.modulation_dialog_app.UIFigure.Visible = 'off';
                app.modulation_dialog_app.UIFigure.Visible = 'on';
            else
                app.modulation_dialog_app = modulation_dialog(app);
            end
        end

        % Button pushed function: ChannelEncodingButton
        function ChannelEncodingButtonPushed(app, event)
            if isobject(app.modulation_dialog_app) && isvalid(app.modulation_dialog_app)
                app.modulation_dialog_app.UIFigure.Visible = 'off';
                app.modulation_dialog_app.UIFigure.Visible = 'on';
            else
                app.modulation_dialog_app = modulation_dialog(app);
            end
        end

        % Button pushed function: ChannelDecodingButton
        function ChannelDecodingButtonPushed(app, event)
            if isobject(app.modulation_dialog_app) && isvalid(app.modulation_dialog_app)
                app.modulation_dialog_app.UIFigure.Visible = 'off';
                app.modulation_dialog_app.UIFigure.Visible = 'on';
            else
                app.modulation_dialog_app = modulation_dialog(app);
            end
        end

        % Button pushed function: PulseShapingButton
        function PulseShapingButtonPushed(app, event)
            if isobject(app.pulse_dialog_app) && isvalid(app.pulse_dialog_app)
                app.pulse_dialog_app.UIFigure.Visible = 'off';
                app.pulse_dialog_app.UIFigure.Visible = 'on';
            else
                app.pulse_dialog_app = pulse_dialog(app);
            end
        end

        % Button pushed function: ChannelButton
        function ChannelButtonPushed(app, event)
            if isobject(app.channel_dialog_app) && isvalid(app.channel_dialog_app)
                app.channel_dialog_app.UIFigure.Visible = 'off';
                app.channel_dialog_app.UIFigure.Visible = 'on';
            else
                app.channel_dialog_app = channel_dialog(app);
            end
        end

        % Button pushed function: EqualizerButton
        function EqualizerButtonPushed(app, event)
            if isobject(app.equalizer_dialog_app) && isvalid(app.equalizer_dialog_app)
                app.equalizer_dialog_app.UIFigure.Visible = 'off';
                app.equalizer_dialog_app.UIFigure.Visible = 'on';
            else
                app.equalizer_dialog_app = equalizer_dialog(app);
            end
        end

        % Image clicked function: MessageArrow
        function MessageArrowImageClicked(app, event)
            if isobject(app.message) && isvalid(app.message)
                app.message.UIFigure.Visible = 'off';
                app.message.UIFigure.Visible = 'on';
            else
                app.message = message_signal(app.param,app.results.message,true);
            end
            %message_signal(app.param,app.results.message,true);
            
        end

        % Image clicked function: MessageHatArrow
        function MessageHatArrowImageClicked(app, event)
            if isobject(app.message_hat) && isvalid(app.message_hat)
                app.message_hat.UIFigure.Visible = 'off';
                app.message_hat.UIFigure.Visible = 'on';
            else
                app.message_hat = message_signal(app.param,app.results.message_hat,false);
            end
            %message_signal(app.param,app.results.message_hat,false);
        end

        % Image clicked function: UArrow
        function UArrowImageClicked(app, event)
            if isobject(app.u) && isvalid(app.u)
                app.u.UIFigure.Visible = 'off';
                app.u.UIFigure.Visible = 'on';
            else
                app.u = u_signal(app.param,app.results.u,true);
            end
        end

        % Image clicked function: CArrow
        function CArrowImageClicked(app, event)
            if isobject(app.c) && isvalid(app.c)
                app.c.UIFigure.Visible = 'off';
                app.c.UIFigure.Visible = 'on';
            else
                app.c = c_signal(app.param,app.results.c);
            end
        end

        % Image clicked function: UHatArrow
        function UHatArrowImageClicked(app, event)
            if isobject(app.u_hat) && isvalid(app.u_hat)
                app.u_hat.UIFigure.Visible = 'off';
                app.u_hat.UIFigure.Visible = 'on';
            else
                app.u_hat = u_signal(app.param,app.results.u_hat,false);
            end
        end

        % Image clicked function: XArrow
        function XArrowImageClicked(app, event)
            if isobject(app.x) && isvalid(app.x)
                app.x.UIFigure.Visible = 'off';
                app.x.UIFigure.Visible = 'on';
            else
                app.x = x_signal(app.param, app.results.x, true);
            end
        end

        % Image clicked function: DArrow
        function DArrowImageClicked(app, event)
            %x_signal(app.param, app.results,false);
            if isobject(app.d) && isvalid(app.d)
                app.d.UIFigure.Visible = 'off';
                app.d.UIFigure.Visible = 'on';
            else
                app.d = equalizer_signal(app.param, app.results.d);
            end
        end

        % Image clicked function: YArrow
        function YArrowImageClicked(app, event)
            if isobject(app.y) && isvalid(app.y)
                app.y.UIFigure.Visible = 'off';
                app.y.UIFigure.Visible = 'on';
            else
                app.y = mf_signal(app.param, app.results.y_os,app.results.y,app.results.y_os_t,app.results.y_t);
            end
        end

        % Image clicked function: SArrow
        function SArrowImageClicked(app, event)
            if isobject(app.s) && isvalid(app.s)
                app.s.UIFigure.Visible = 'off';
                app.s.UIFigure.Visible = 'on';
            else
                app.s = s_signal(app.param, app.results.s, app.results.s_t, true);
            end
        end

        % Image clicked function: RArrow
        function RArrowImageClicked(app, event)
            if isobject(app.r) && isvalid(app.r)
                app.r.UIFigure.Visible = 'off';
                app.r.UIFigure.Visible = 'on';
            else
                app.r = r_signal(app.param, app.results.r, app.results.r_tmp, app.results.r_t, app.results.r_tmp_t);
            end
            %s_signal(app.param, app.results.r, true);
        end

        % Image clicked function: LArrow
        function LArrowImageClicked(app, event)
            if isobject(app.L) && isvalid(app.L)
                app.L.UIFigure.Visible = 'off';
                app.L.UIFigure.Visible = 'on';
            else 
                app.L = demapped_signal(app.param, app.results.L);
            end
        end

        % Image clicked function: SHFArrow
        function SHFArrowImageClicked(app, event)
            if isobject(app.s_hf) && isvalid(app.s_hf)
                app.s_hf.UIFigure.Visible = 'off';
                app.s_hf.UIFigure.Visible = 'on';
            else
                app.s_hf = s_hf_signal(app.param, app.results.s_hf, app.results.s_hf_t, true);
            end
            
        end

        % Image clicked function: RHFArrow
        function RHFArrowImageClicked(app, event)
            
            if isobject(app.r_hf) && isvalid(app.r_hf)
                app.r_hf.UIFigure.Visible = 'off';
                app.r_hf.UIFigure.Visible = 'on';
            else
                if app.CarrierModulationCheckBox.Value
                    app.r_hf = s_hf_signal(app.param, app.results.r_hf, app.results.r_hf_t, false);
                elseif app.PulseShapingCheckBox.Value
                    app.r_hf = s_signal(app.param, app.results.r_hf, app.results.r_hf_t, false);
                else
                    app.r_hf = y_discrete_signal(app.param, app.results.r_hf, 'Recieved Symbols y');
                end
            end
            
        end

        % Button pushed function: CarrierModulationButton
        function CarrierModulationButtonPushed(app, event)
            carrier_dialog(app);
        end

        % Button pushed function: CarrierDemodulationButton
        function CarrierDemodulationButtonPushed(app, event)
            carrier_dialog(app);
        end

        % Value changed function: SourceCodingCheckBox
        function SourceCodingCheckBoxValueChanged(app, event)
            value = app.SourceCodingCheckBox.Value;
            helper.change_active_blocks(app,'source_coding',value);
        end

        % Value changed function: ChannelCheckBox
        function ChannelCheckBoxValueChanged(app, event)
            value = app.ChannelCheckBox.Value;
            helper.change_active_blocks(app,'channel',value);
        end

        % Value changed function: ModulationCheckBox
        function ModulationCheckBoxValueChanged(app, event)
            value = app.ModulationCheckBox.Value;
            helper.change_active_blocks(app,'modulation',value);
        end

        % Value changed function: ChannelCodingCheckBox
        function ChannelCodingCheckBoxValueChanged(app, event)
            value = app.ChannelCodingCheckBox.Value;
            helper.change_active_blocks(app,'channel_coding',value);
        end

        % Value changed function: PulseShapingCheckBox
        function PulseShapingCheckBoxValueChanged(app, event)
            value = app.PulseShapingCheckBox.Value;
            helper.change_active_blocks(app,'pulse_shaping',value);
        end

        % Value changed function: CarrierModulationCheckBox
        function CarrierModulationCheckBoxValueChanged(app, event)
            value = app.CarrierModulationCheckBox.Value;
            helper.change_active_blocks(app,'carrier_modulation',value);
        end

        % Value changed function: EqualizerCheckBox
        function EqualizerCheckBoxValueChanged(app, event)
            value = app.EqualizerCheckBox.Value;
            helper.change_active_blocks(app,'equalizer',value);
        end

        % Menu selected function: ModulationMenu
        function ModulationMenuSelected(app, event)
            helper.initialize_lab_param(app,'modulation');
            app.disable_arrows();
            helper.close_open_signals(app);
            helper.close_open_dialogs(app);
            app.update_shown_parameters();
        end

        % Menu selected function: ChannelCodingMenu
        function ChannelCodingMenuSelected(app, event)
            helper.initialize_lab_param(app,'channel_coding');
            app.disable_arrows();
            helper.close_open_signals(app);
            helper.close_open_dialogs(app);
            app.update_shown_parameters();
        end

        % Menu selected function: SourceCodingMenu
        function SourceCodingMenuSelected(app, event)
            helper.initialize_lab_param(app,'source_coding');
            app.disable_arrows();
            helper.close_open_signals(app);
            helper.close_open_dialogs(app);
            app.update_shown_parameters();
        end

        % Menu selected function: PulseShapingMenu
        function PulseShapingMenuSelected(app, event)
            helper.initialize_lab_param(app, 'pulse_shaping');
            app.disable_arrows();
            helper.close_open_signals(app);
            helper.close_open_dialogs();
            app.update_shown_parameters();
        end

        % Value changed function: OversamplingFactorSpinner
        function OversamplingFactorSpinnerValueChanged(app, event)
            value = app.OversamplingFactorSpinner.Value;
            app.param.general.os = value;
            app.UpToDateLabel.Visible = 'on';
        end

        % Value changed function: SymbolRateEditField
        function SymbolRateEditFieldValueChanged(app, event)
            value = app.SymbolRateEditField.Value;
            app.param.general.symbol_rate = value;
            app.UpToDateLabel.Visible = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BasicCommLabSystemUIFigure = uifigure('Visible', 'off');
            app.BasicCommLabSystemUIFigure.Name = 'Basic/Comm Lab: System';
            app.BasicCommLabSystemUIFigure.CloseRequestFcn = createCallbackFcn(app, @BasicCommLabSystemUIFigureCloseRequest, true);
            app.PresettingsMenu = uimenu(app.BasicCommLabSystemUIFigure);
            app.PresettingsMenu.Text = 'Presettings';
            app.SourceCodingMenu = uimenu(app.PresettingsMenu);
            app.SourceCodingMenu.MenuSelectedFcn = createCallbackFcn(app, @SourceCodingMenuSelected, true);
            app.SourceCodingMenu.Text = 'Source Coding';
            app.ModulationMenu = uimenu(app.PresettingsMenu);
            app.ModulationMenu.MenuSelectedFcn = createCallbackFcn(app, @ModulationMenuSelected, true);
            app.ModulationMenu.Text = 'Modulation';
            app.ChannelCodingMenu = uimenu(app.PresettingsMenu);
            app.ChannelCodingMenu.MenuSelectedFcn = createCallbackFcn(app, @ChannelCodingMenuSelected, true);
            app.ChannelCodingMenu.Text = 'Channel Coding';
            app.PulseShapingMenu = uimenu(app.PresettingsMenu);
            app.PulseShapingMenu.MenuSelectedFcn = createCallbackFcn(app, @PulseShapingMenuSelected, true);
            app.PulseShapingMenu.Text = 'Pulse Shaping';
            app.GridLayout = uigridlayout(app.BasicCommLabSystemUIFigure);
            app.MessageArrow = uiimage(app.GridLayout);
            app.MessageArrow.ImageClickedFcn = createCallbackFcn(app, @MessageArrowImageClicked, true);
            app.MessageArrow.Enable = 'off';
            app.SourceButton = uibutton(app.GridLayout, 'push');
            app.SourceButton.ButtonPushedFcn = createCallbackFcn(app, @SourceButtonPushed, true);
            app.SourceButton.Text = 'Source';
            app.SourceEncodingButton = uibutton(app.GridLayout, 'push');
            app.SourceEncodingButton.ButtonPushedFcn = createCallbackFcn(app, @SourceEncodingButtonPushed, true);
            app.SourceEncodingButton.Text = {'Source'; 'Encoding'};
            app.UArrow = uiimage(app.GridLayout);
            app.UArrow.ImageClickedFcn = createCallbackFcn(app, @UArrowImageClicked, true);
            app.UArrow.Enable = 'off';
            app.ChannelEncodingButton = uibutton(app.GridLayout, 'push');
            app.ChannelEncodingButton.ButtonPushedFcn = createCallbackFcn(app, @ChannelEncodingButtonPushed, true);
            app.ChannelEncodingButton.Text = {'Channel'; 'Encoding'};
            app.CArrow = uiimage(app.GridLayout);
            app.CArrow.ImageClickedFcn = createCallbackFcn(app, @CArrowImageClicked, true);
            app.CArrow.Enable = 'off';
            app.ModulationButton = uibutton(app.GridLayout, 'push');
            app.ModulationButton.ButtonPushedFcn = createCallbackFcn(app, @ModulationButtonPushed, true);
            app.ModulationButton.Text = 'Modulation';
            app.SinkButton = uibutton(app.GridLayout, 'push');
            app.SinkButton.Text = 'Sink';
            app.SourceDecodingButton = uibutton(app.GridLayout, 'push');
            app.SourceDecodingButton.ButtonPushedFcn = createCallbackFcn(app, @SourceDecodingButtonPushed, true);
            app.SourceDecodingButton.Text = {'Source'; 'Decoding'};
            app.ChannelDecodingButton = uibutton(app.GridLayout, 'push');
            app.ChannelDecodingButton.ButtonPushedFcn = createCallbackFcn(app, @ChannelDecodingButtonPushed, true);
            app.ChannelDecodingButton.Text = {'Channel'; 'Decoding'};
            app.DemapperButton = uibutton(app.GridLayout, 'push');
            app.DemapperButton.ButtonPushedFcn = createCallbackFcn(app, @DemapperButtonPushed, true);
            app.DemapperButton.Text = 'Demapper';
            app.RunButton = uibutton(app.GridLayout, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.MessageHatArrow = uiimage(app.GridLayout);
            app.MessageHatArrow.ImageClickedFcn = createCallbackFcn(app, @MessageHatArrowImageClicked, true);
            app.MessageHatArrow.Enable = 'off';
            app.UHatArrow = uiimage(app.GridLayout);
            app.UHatArrow.ImageClickedFcn = createCallbackFcn(app, @UHatArrowImageClicked, true);
            app.UHatArrow.Enable = 'off';
            app.LArrow = uiimage(app.GridLayout);
            app.LArrow.ImageClickedFcn = createCallbackFcn(app, @LArrowImageClicked, true);
            app.LArrow.Enable = 'off';
            app.PulseShapingButton = uibutton(app.GridLayout, 'push');
            app.PulseShapingButton.ButtonPushedFcn = createCallbackFcn(app, @PulseShapingButtonPushed, true);
            app.PulseShapingButton.Text = {'Pulse'; 'Shaping'};
            app.ReceiveFilterButton = uibutton(app.GridLayout, 'push');
            app.ReceiveFilterButton.Text = {'Receive'; 'Filter'};
            app.XArrow = uiimage(app.GridLayout);
            app.XArrow.ImageClickedFcn = createCallbackFcn(app, @XArrowImageClicked, true);
            app.XArrow.Enable = 'off';
            app.DArrow = uiimage(app.GridLayout);
            app.DArrow.ImageClickedFcn = createCallbackFcn(app, @DArrowImageClicked, true);
            app.DArrow.Enable = 'off';
            app.CarrierModulationButton = uibutton(app.GridLayout, 'push');
            app.CarrierModulationButton.ButtonPushedFcn = createCallbackFcn(app, @CarrierModulationButtonPushed, true);
            app.CarrierModulationButton.Text = {'Carrier'; 'Modulation'};
            app.CarrierDemodulationButton = uibutton(app.GridLayout, 'push');
            app.CarrierDemodulationButton.ButtonPushedFcn = createCallbackFcn(app, @CarrierDemodulationButtonPushed, true);
            app.CarrierDemodulationButton.Text = {'Carrier'; 'Demodulation'};
            app.SArrow = uiimage(app.GridLayout);
            app.SArrow.ImageClickedFcn = createCallbackFcn(app, @SArrowImageClicked, true);
            app.SArrow.Enable = 'off';
            app.RArrow = uiimage(app.GridLayout);
            app.RArrow.ImageClickedFcn = createCallbackFcn(app, @RArrowImageClicked, true);
            app.RArrow.Enable = 'off';
            app.ChannelButton = uibutton(app.GridLayout, 'push');
            app.ChannelButton.ButtonPushedFcn = createCallbackFcn(app, @ChannelButtonPushed, true);
            app.ChannelButton.Text = 'Channel';
            app.SHFArrow = uiimage(app.GridLayout);
            app.SHFArrow.ImageClickedFcn = createCallbackFcn(app, @SHFArrowImageClicked, true);
            app.SHFArrow.Enable = 'off';
            app.RHFArrow = uiimage(app.GridLayout);
            app.RHFArrow.ImageClickedFcn = createCallbackFcn(app, @RHFArrowImageClicked, true);
            app.RHFArrow.Enable = 'off';
            app.ParameterButton = uibutton(app.GridLayout, 'push');
            app.ParameterButton.ButtonPushedFcn = createCallbackFcn(app, @ParameterButtonPushed, true);
            app.ParameterButton.Text = {'Show'; 'Parameter'};
            app.ActiveBlocksPanel = uipanel(app.GridLayout);
            app.ActiveBlocksPanel.Title = 'Active Blocks';
            app.GridLayout2 = uigridlayout(app.ActiveBlocksPanel);
            app.SourceCodingCheckBox = uicheckbox(app.GridLayout2);
            app.SourceCodingCheckBox.ValueChangedFcn = createCallbackFcn(app, @SourceCodingCheckBoxValueChanged, true);
            app.SourceCodingCheckBox.Text = 'Source Coding';
            app.ChannelCodingCheckBox = uicheckbox(app.GridLayout2);
            app.ChannelCodingCheckBox.ValueChangedFcn = createCallbackFcn(app, @ChannelCodingCheckBoxValueChanged, true);
            app.ChannelCodingCheckBox.Text = 'Channel Coding';
            app.ModulationCheckBox = uicheckbox(app.GridLayout2);
            app.ModulationCheckBox.ValueChangedFcn = createCallbackFcn(app, @ModulationCheckBoxValueChanged, true);
            app.ModulationCheckBox.Text = 'Modulation';
            app.PulseShapingCheckBox = uicheckbox(app.GridLayout2);
            app.PulseShapingCheckBox.ValueChangedFcn = createCallbackFcn(app, @PulseShapingCheckBoxValueChanged, true);
            app.PulseShapingCheckBox.Text = 'Pulse Shaping';
            app.CarrierModulationCheckBox = uicheckbox(app.GridLayout2);
            app.CarrierModulationCheckBox.ValueChangedFcn = createCallbackFcn(app, @CarrierModulationCheckBoxValueChanged, true);
            app.CarrierModulationCheckBox.Text = 'Carrier Modulation';
            app.EqualizerCheckBox = uicheckbox(app.GridLayout2);
            app.EqualizerCheckBox.ValueChangedFcn = createCallbackFcn(app, @EqualizerCheckBoxValueChanged, true);
            app.EqualizerCheckBox.Text = 'Equalizer';
            app.ChannelCheckBox = uicheckbox(app.GridLayout2);
            app.ChannelCheckBox.ValueChangedFcn = createCallbackFcn(app, @ChannelCheckBoxValueChanged, true);
            app.ChannelCheckBox.Text = 'Channel';
            app.EqualizerButton = uibutton(app.GridLayout, 'push');
            app.EqualizerButton.ButtonPushedFcn = createCallbackFcn(app, @EqualizerButtonPushed, true);
            app.EqualizerButton.Text = 'Equalizer';
            app.YArrow = uiimage(app.GridLayout);
            app.YArrow.ImageClickedFcn = createCallbackFcn(app, @YArrowImageClicked, true);
            app.YArrow.Enable = 'off';
            app.ResultsPanel = uipanel(app.GridLayout);
            app.ResultsPanel.Title = 'Results';
            app.GridLayout3 = uigridlayout(app.ResultsPanel);
            app.CompressionLabel = uilabel(app.GridLayout3);
            app.CompressionLabel.Text = 'Compression:';
            app.CompressionResult = uilabel(app.GridLayout3);
            app.CompressionResult.Text = '';
            app.EndToEndSerLabel = uilabel(app.GridLayout3);
            app.EndToEndSerLabel.Text = 'End-to-End Symbol error rate (SER):';
            app.EndToEndSerResult = uilabel(app.GridLayout3);
            app.EndToEndSerResult.Text = '';
            app.BitErrorRateLabel = uilabel(app.GridLayout3);
            app.BitErrorRateLabel.Text = 'Bit Error Rate';
            app.BER = uilabel(app.GridLayout3);
            app.BER.Text = '';
            app.ExportParamButton = uibutton(app.GridLayout, 'push');
            app.ExportParamButton.ButtonPushedFcn = createCallbackFcn(app, @ExportParamButtonPushed, true);
            app.ExportParamButton.Text = {'Export'; 'Param'};
            app.UpToDateLabel = uilabel(app.GridLayout);
            app.UpToDateLabel.Text = 'No Results available! Click Run button to run simulation.';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.OversamplingFactorSpinnerLabel = uilabel(app.GridLayout4);
            app.OversamplingFactorSpinnerLabel.Text = 'Oversampling Factor';
            app.OversamplingFactorSpinner = uispinner(app.GridLayout4);
            app.OversamplingFactorSpinner.Limits = [10 Inf];
            app.OversamplingFactorSpinner.RoundFractionalValues = 'on';
            app.OversamplingFactorSpinner.ValueChangedFcn = createCallbackFcn(app, @OversamplingFactorSpinnerValueChanged, true);
            app.OversamplingFactorSpinner.Value = 10;
            app.SymbolRateSpinnerLabel = uilabel(app.GridLayout4);
            app.SymbolRateSpinnerLabel.Text = 'Symbol Rate';
            app.SymbolRateEditField = uispinner(app.GridLayout4);
            app.SymbolRateEditField.Limits = [1 Inf];
            app.SymbolRateEditField.ValueDisplayFormat = '%11.4g [Hz]';
            app.SymbolRateEditField.ValueChangedFcn = createCallbackFcn(app, @SymbolRateEditFieldValueChanged, true);
            app.SymbolRateEditField.Value = 1;
            % Show the figure after all components are created
            app.BasicCommLabSystemUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = basic_lab_main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BasicCommLabSystemUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BasicCommLabSystemUIFigure)
        end
    end
end